classdef main_interface_english < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TimeEditField                 matlab.ui.control.EditField
        Label_12                      matlab.ui.control.Label
        cavitylengthEditField         matlab.ui.control.NumericEditField
        mmLabel                       matlab.ui.control.Label
        cavitywidthEditField          matlab.ui.control.NumericEditField
        Label_11                      matlab.ui.control.Label
        highestscoringoutcomesButton  matlab.ui.control.Button
        StructureandscoreofalloutcomesButton  matlab.ui.control.Button
        Image2_2                      matlab.ui.control.Image
        backButton                    matlab.ui.control.Button
        AbscoefficientEditField       matlab.ui.control.NumericEditField
        Label_10                      matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        Label_9                       matlab.ui.control.Label
        IntelligentdesignsoftwareforacousticmetamaterialsLabel  matlab.ui.control.Label
        InputdesignobjectivesButton   matlab.ui.control.Button
        K_rEditField                  matlab.ui.control.NumericEditField
        K_rEditFieldLabel             matlab.ui.control.Label
        K_hEditField                  matlab.ui.control.NumericEditField
        K_hEditFieldLabel             matlab.ui.control.Label
        IterationsEditField           matlab.ui.control.NumericEditField
        Label_7                       matlab.ui.control.Label
        EpisodeEditField              matlab.ui.control.NumericEditField
        Label_6                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        MeanthicknessEditField        matlab.ui.control.EditField
        scoreEditField                matlab.ui.control.NumericEditField
        Label_5                       matlab.ui.control.Label
        meanerrorEditField            matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        ExpectederrorEditField        matlab.ui.control.NumericEditField
        DisplaydesignobjectivesTextArea  matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        ModelRunButton                matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % target_frequent = []; % 存储设定目标频率的数组
        % aim_alph = []; % 存储设定目标吸声系数的数组
        fre_alph = [];%储存目标频率和目标吸声系数属性
        aim_alph_ero = [];%存储输入的期望误差
        epochs = [];%存储训练轮数
        max_round = [];%存储输入的最大回合数
        k_h = [];%存储评价模型厚度系数权重
        k_e = [];%储存评价模型吸声系数权重
        qk = [];%存储设定的腔宽
        qc = [];%储存设定的腔长
        adjust_cell = [];%用来存储所有符合条件的结果参数
        result_sore = [];%用来存储所有符合条件的结果的评分
     
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % 获取鼠标点击的坐标
            clickPoint = app.UIAxes.CurrentPoint; % clickPoint是一个2x3的数组，包含点击点的x, y, z坐标
            x = round(clickPoint(1,1));
            y = clickPoint(1,2);
            % 将x和y作为一对数据添加到fre_alph数组中
            app.fre_alph(end + 1, :) = [x, y];

            % 对fre_alph数组进行排序，以第一列(x)为标准，从小到大排序
            % 注意保持x和y之间的对应关系
            app.fre_alph = sortrows(app.fre_alph, 1);

            % 在点击的位置绘制一个蓝色的圆点
            % 这里使用了scatter函数来绘制圆点，'filled'参数使圆点实心
            %用plot函数在app.UIAxes上绘制星型标记
            plot(app.UIAxes, x, y, 'p', 'MarkerSize', 10, 'MarkerEdgeColor', 'b', 'MarkerFaceColor', 'b');

            % 在圆点旁边显示坐标
            % 使用text函数添加文本，这里的x+0.02和y+0.02是为了稍微偏移文本，使其不会覆盖在圆点上
            text(app.UIAxes, x-17, y+0.05, ['(', num2str(x, '%d'), ', ', num2str(y, '%.2f'), ')'], 'Color', 'r');
            % 更新TextArea的内容，显示所有点击的坐标
            %初始化一个字符串来存储坐标
            coordinatesStr = 'Design Objectives: \n';
            % 循环遍历fre_alph数组，将每个坐标添加到字符串中
            for i = 1:size(app.fre_alph, 1)
                coordinatesStr = strcat(coordinatesStr, sprintf('(%d, %0.2f)\n', app.fre_alph(i, 1), app.fre_alph(i, 2)));
            end
            % 将最终的字符串设置为TextArea的值
            app.DisplaydesignobjectivesTextArea.Value = coordinatesStr;
        end

        % Button pushed function: ModelRunButton
        function ModelRunButtonPushed(app, event)
            try
                if ~isempty(app.fre_alph)

                    %当点击确认计算按钮时调用吸声超材料逆向设计程序
                    % 更改鼠标光标为等待状态
                    app.UIFigure.Pointer = 'watch';
                    drawnow; % 立即更新图形界面

                    % 当点击确认计算按钮时调用吸声超材料逆向设计程序
                    app.aim_alph_ero = app.ExpectederrorEditField.Value;
                    app.epochs = app.EpisodeEditField.Value;
                    app.max_round = app.IterationsEditField.Value;
                    app.k_h = app.K_hEditField.Value;
                    app.k_e = app.K_rEditField.Value;
                    app.qk = app.cavitywidthEditField.Value;
                    app.qc = app.cavitylengthEditField.Value;
                    %开始运行主函数设计吸声超材料
                    result=function_use_rein_learning(app.fre_alph(:,1)',app.fre_alph(:,2)',app.aim_alph_ero,app.epochs,app.max_round,app.k_h,app.k_e,app.qk,app.qc);
                    max_result = result{1}; % 接受第一个返回值max_result
                    max_result_plot = result{2}; % 接受第二个返回值result_plot_cell
                    mean_error = result{3}; %接受第三个返回值平均误差
                    max_result_value = result{4};%接受第4个返回值-最优结果的评分
                    app.adjust_cell = result{5};%接受第5个返回值-所有结构参数
                    app.result_sore = result{6};%接受第6个返回值-所有结果的评分
                    time= result{7};%接受第7个返回值-模型运行消耗的时间

                    %% 在uitable中显示adjust_cell数组
                    app.UITable.Data = max_result(:,2:8);
                    %%

                    %% 使用result_plot_cell中的数据进行绘图
                    % 假设result_plot_cell的第一行是X坐标，第二行是Y坐标
                    plot(app.UIAxes, max_result_plot(1,:), max_result_plot(2,:));
                    % 根据需要设置更多图表属性
                    title(app.UIAxes, 'Result Plot');
                    %xlabel(app.UIAxes, 'X Axis');
                    %ylabel(app.UIAxes, 'Y Axis');
                    %%

                    %% 将平均误差，平均高度，评分价值、时间显示在标签中
                    app.meanerrorEditField.Value=mean_error;
                    app.MeanthicknessEditField.Value=sprintf('%.1f（mm）',mean(result{1}(:,8)));
                    app.scoreEditField.Value=max_result_value;
                    app.TimeEditField.Value=time;

                    % 执行完毕后，恢复鼠标光标
                    app.UIFigure.Pointer = 'arrow';

                else
                    uialert(app.UIFigure, 'Please enter the initial target coefficients and then click on Confirm', 'chuyin');
                end
            catch ME
                uialert(app.UIFigure, ['Something seems to be wrong, maybe the target set is out of the design domain: ', ME.message], 'chuyin');
            end

            
            %collaborate_matlab(app.adjust_cell,app.result_sore);
            try
                % 尝试执行collaborate_matlab函数
                collaborate_matlab(app.adjust_cell,app.result_sore);
                % 如果成功执行，则显示COMSOL连接成功的消息
                % 读取图像
                img = imread('structure_picture/all_structure.png');
                % 显示图像在 UIImage 组件中
                app.Image2.ImageSource = img;
                % 读取图像
                img = imread('structure_picture/best_structure.png');
                % 显示图像在 UIImage 组件中
                app.Image2_2.ImageSource = img;
                
                %uialert(app.UIFigure, 'Structure successfully drawn', 'chuyu');
               
            catch ME
                % 如果执行过程中出现错误，则捕获错误并显示COMSOL连接失败的消息
                % ME是一个MException对象，包含了错误信息
                %uialert(app.UIFigure, ['COMSOL connection failure: ', ME.message], 'chuyin');
                %uialert(['COMSOL连接失败: ', ME.message], '失败');
            end

            % %调用matlab联合画出图形
            % collaborate_matlab(app.adjust_cell,app.result_sore);

        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end

        % Value changed function: meanerrorEditField
        function meanerrorEditFieldValueChanged(app, event)
            value = app.meanerrorEditField.Value;
            
        end

        % Value changed function: MeanthicknessEditField
        function MeanthicknessEditFieldValueChanged(app, event)
            value = app.MeanthicknessEditField.Value;
            
        end

        % Value changed function: scoreEditField
        function scoreEditFieldValueChanged(app, event)
            value = app.scoreEditField.Value;
            
        end

        % Callback function
        function ImageClicked(app, event)
            delete(app); % 关闭窗口
        end

        % Value changed function: ExpectederrorEditField
        function ExpectederrorEditFieldValueChanged(app, event)
            value = app.ExpectederrorEditField.Value;
          
            
        end

        % Value changed function: EpisodeEditField
        function EpisodeEditFieldValueChanged(app, event)
            value = app.EpisodeEditField.Value;
            
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            
        end

        % Button pushed function: InputdesignobjectivesButton
        function InputdesignobjectivesButtonPushed(app, event)
            x = round(app.FrequencyEditField.Value);%手动输入横坐标
            y = app.AbscoefficientEditField.Value;%手动输入纵坐标

            % 将x和y作为一对数据添加到fre_alph数组中
            app.fre_alph(end + 1, :) = [x, y];
            % 对fre_alph数组进行排序，以第一列(x)为标准，从小到大排序
            % 注意保持x和y之间的对应关系
            app.fre_alph = sortrows(app.fre_alph, 1);
            % 在点击的位置绘制一个蓝色的圆点
            % 这里使用了scatter函数来绘制圆点，'filled'参数使圆点实心
            plot(app.UIAxes, app.fre_alph(:,1), app.fre_alph(:,2), 'p', 'MarkerSize', 10, 'MarkerEdgeColor', 'b', 'MarkerFaceColor', 'b');

            %scatter(app.UIAxes,app.fre_alph(:,1),app.fre_alph(:,2), 100, 'b', 'filled'); % 100是圆点的大小，'b'指定颜色为蓝色
            % 在圆点旁边显示坐标
            % 使用text函数添加文本，这里的x+0.02和y+0.02是为了稍微偏移文本，使其不会覆盖在圆点上
            text(app.UIAxes, x-17, y+0.05, ['(', num2str(x, '%d'), ', ', num2str(y, '%.2f'), ')'], 'Color', 'r');
            %初始化一个字符串来存储坐标
            coordinatesStr = 'Design Objectives: \n';
            % 循环遍历fre_alph数组，将每个坐标添加到字符串中
            for i = 1:size(app.fre_alph, 1)
                coordinatesStr = strcat(coordinatesStr, sprintf('(%d, %0.2f)\n', app.fre_alph(i, 1), app.fre_alph(i, 2)));
            end
            % 将最终的字符串设置为TextArea的值
            app.DisplaydesignobjectivesTextArea.Value = coordinatesStr;
        end

        % Value changed function: AbscoefficientEditField
        function AbscoefficientEditFieldValueChanged(app, event)
         
            
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            try
                app.fre_alph(end,:)=[];
                % 在点击的位置绘制一个蓝色的圆点
                % 这里使用了scatter函数来绘制圆点，'filled'参数使圆点实心
                cla(app.UIAxes); % 清除app.UIAxes上的图形
                %scatter(app.UIAxes,app.fre_alph(:,1), app.fre_alph(:,2), 100, 'b', 'filled'); % 100是圆点的大小，'b'指定颜色为蓝色
                plot(app.UIAxes, app.fre_alph(:,1), app.fre_alph(:,2), 'p', 'MarkerSize', 10, 'MarkerEdgeColor', 'b', 'MarkerFaceColor', 'b');


                %初始化一个字符串来存储坐标
                coordinatesStr = 'Design Objectives: \n';
                % 循环遍历fre_alph数组，将每个坐标添加到字符串中
                for i = 1:size(app.fre_alph, 1)
                    text(app.UIAxes, app.fre_alph(i,1)-17, app.fre_alph(i,2)+0.05, ['(', num2str(app.fre_alph(i,1), '%d'), ', ', num2str(app.fre_alph(i,2), '%.2f'), ')'], 'Color', 'r');
                    coordinatesStr = strcat(coordinatesStr, sprintf('(%d, %0.2f)\n', app.fre_alph(i, 1), app.fre_alph(i, 2)));
                end
                % 将最终的字符串设置为TextArea的值
                app.DisplaydesignobjectivesTextArea.Value = coordinatesStr;

                %将图片变回来
                
            catch ME
                uialert(app.UIFigure, ['This button can only be pressed after the target point has been set.', ME.message], 'chuyin');
            end
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)


        end

        % Button pushed function: StructureandscoreofalloutcomesButton
        function StructureandscoreofalloutcomesButtonPushed(app, event)
            try
                % 读取图像
                img = imread('structure_picture/all_structure.png');
                % 显示图像在 UIImage 组件中
                app.Image2.ImageSource = img;
            catch ME
                uialert(app.UIFigure, ['Unable to display structure picture', ME.message], 'chuyin');
            end
        end

        % Button pushed function: highestscoringoutcomesButton
        function highestscoringoutcomesButtonPushed(app, event)
            try
                % 读取图像
                img = imread('structure_picture/best_structure.png');
                % 显示图像在 UIImage 组件中
                app.Image2_2.ImageSource = img;
            catch ME
                uialert(app.UIFigure, ['Unable to display structure picture', ME.message], 'chuyin');
            end
        end

        % Callback function
        function DisplayDesignStructureButtonPushed(app, event)
            %collaborate_matlab(app.adjust_cell,app.result_sore);
            try
                % 尝试执行collaborate_matlab函数
                collaborate_matlab(app.adjust_cell,app.result_sore);
                % 如果成功执行，则显示COMSOL连接成功的消息
                
                uialert(app.UIFigure, 'Structure successfully drawn', 'chuyu');
               
            catch ME
                % 如果执行过程中出现错误，则捕获错误并显示COMSOL连接失败的消息
                % ME是一个MException对象，包含了错误信息
                uialert(app.UIFigure, ['COMSOL connection failed. ', ME.message], 'chuyin');
                %uialert(['COMSOL连接失败: ', ME.message], '失败');
            end

            % %调用matlab联合画出图形
            % collaborate_matlab(app.adjust_cell,app.result_sore);
        end

        % Value changed function: DisplaydesignobjectivesTextArea
        function DisplaydesignobjectivesTextAreaValueChanged(app, event)
            value = app.DisplaydesignobjectivesTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Design Objectives and Design outcomss absorption coefficient curve')
            xlabel(app.UIAxes, 'Frequent(Hz)')
            ylabel(app.UIAxes, 'Absorption coefficient(α)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [30 600];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [30 60 90 120 150 180 210 240 270 300 330 360 390 420 450 480 510 540 570 600 630];
            app.UIAxes.XTickLabel = {'30'; '60'; '90'; '120'; '150'; '180'; '210'; '240'; '270'; '300'; '330'; '360'; '390'; '420'; '450'; '480'; '510'; '540'; '570'; '600'; '630'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.ModelRunButton = uibutton(app.UIFigure, 'push');
            app.ModelRunButton.ButtonPushedFcn = createCallbackFcn(app, @ModelRunButtonPushed, true);
            app.ModelRunButton.Text = 'Model Run';
            app.ModelRunButton.Icon = fullfile(pathToMLAPP, 'app_figuer', 'wei1.png');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Expected  error';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Display  design objectives';
            app.DisplaydesignobjectivesTextArea = uitextarea(app.UIFigure);
            app.DisplaydesignobjectivesTextArea.ValueChangedFcn = createCallbackFcn(app, @DisplaydesignobjectivesTextAreaValueChanged, true);
            app.ExpectederrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExpectederrorEditField.ValueChangedFcn = createCallbackFcn(app, @ExpectederrorEditFieldValueChanged, true);
            app.ExpectederrorEditField.Value = 0.03;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'     W(mm)'; 'L(mm)'; 'r(mm)'; 'H(mm)'; 'h_d(mm)'; 'h_t(mm)'; 'Total Thickness(mm)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'mean error';
            app.meanerrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.meanerrorEditField.AllowEmpty = 'on';
            app.meanerrorEditField.ValueChangedFcn = createCallbackFcn(app, @meanerrorEditFieldValueChanged, true);
            app.meanerrorEditField.Value = [];
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'score';
            app.scoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.scoreEditField.ValueChangedFcn = createCallbackFcn(app, @scoreEditFieldValueChanged, true);
            app.MeanthicknessEditField = uieditfield(app.UIFigure, 'text');
            app.MeanthicknessEditField.ValueChangedFcn = createCallbackFcn(app, @MeanthicknessEditFieldValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Mean thickness';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Episode';
            app.EpisodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpisodeEditField.ValueChangedFcn = createCallbackFcn(app, @EpisodeEditFieldValueChanged, true);
            app.EpisodeEditField.Value = 9;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = ' Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Value = 300;
            app.K_hEditFieldLabel = uilabel(app.UIFigure);
            app.K_hEditFieldLabel.Text = '  K_h';
            app.K_hEditField = uieditfield(app.UIFigure, 'numeric');
            app.K_hEditField.Value = 0.1;
            app.K_rEditFieldLabel = uilabel(app.UIFigure);
            app.K_rEditFieldLabel.Text = '  K_r';
            app.K_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.K_rEditField.Value = 0.9;
            app.InputdesignobjectivesButton = uibutton(app.UIFigure, 'push');
            app.InputdesignobjectivesButton.ButtonPushedFcn = createCallbackFcn(app, @InputdesignobjectivesButtonPushed, true);
            app.InputdesignobjectivesButton.Text = 'Input design objectives';
            app.IntelligentdesignsoftwareforacousticmetamaterialsLabel = uilabel(app.UIFigure);
            app.IntelligentdesignsoftwareforacousticmetamaterialsLabel.Text = 'Intelligent design software for acoustic metamaterials';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'Abs-coefficient';
            app.AbscoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.AbscoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @AbscoefficientEditFieldValueChanged, true);
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = 'back';
            app.Image2_2 = uiimage(app.UIFigure);
            app.StructureandscoreofalloutcomesButton = uibutton(app.UIFigure, 'push');
            app.StructureandscoreofalloutcomesButton.ButtonPushedFcn = createCallbackFcn(app, @StructureandscoreofalloutcomesButtonPushed, true);
            app.StructureandscoreofalloutcomesButton.Text = 'Structure and score of all outcomes';
            app.highestscoringoutcomesButton = uibutton(app.UIFigure, 'push');
            app.highestscoringoutcomesButton.ButtonPushedFcn = createCallbackFcn(app, @highestscoringoutcomesButtonPushed, true);
            app.highestscoringoutcomesButton.Text = 'highest scoring  outcomes';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'cavity width';
            app.cavitywidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.cavitywidthEditField.Value = 20;
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'cavity length';
            app.cavitylengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.cavitylengthEditField.Value = 20;
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_interface_english

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end